classdef termo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        IRRefImage                     matlab.ui.control.Image
        ChoosereferenceIRimageButton   matlab.ui.control.Button
        VISRefImage                    matlab.ui.control.Image
        ChoosereferenceVISimageButton  matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        ResultLabel                    matlab.ui.control.Label
        GridLayout                     matlab.ui.container.GridLayout
    end

    
    properties (Access = public)
        IRRefPoints, VISRefPoints % Description
    end
    
    methods (Access = private)
        
        function [X, Y] = selectMarkerPoints(app, img)
            f = figure;

            imagesc(img);

            pointsNumber = 4;
            title("Select 4 marker points");
            X = [];
            Y = [];
            
            for i=1:pointsNumber
                [x, y] = ginput(1);
                
                hold on
                plot(x(1), y(1), 'r*');
                text(x, y, ['  ', num2str(i)], 'Color', 'r');
                drawnow
                X = [X x];
                Y = [Y y];
            end
            close(f);
            
            [X, XIdx] = sort(X);
            Y = Y(XIdx);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChoosereferenceIRimageButton
        function ChoosereferenceIRimageButtonPushed(app, event)
            [file,path] = uigetfile('*.png', 'Select a Reference IR Image File');
            app.IRRefImage.ImageSource = fullfile(path,file);
            img = imread(fullfile(path,file));
            
            [app.IRRefPoints.X, app.IRRefPoints.Y] = app.selectMarkerPoints(img);

        end

        % Button pushed function: ChoosereferenceVISimageButton
        function ChoosereferenceVISimageButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg', 'Select a Reference VIS Image File');
            app.VISRefImage.ImageSource = fullfile(path,file);
            img = imread(fullfile(path,file));
            
            [app.VISRefPoints.X, app.VISRefPoints.Y] = app.selectMarkerPoints(img);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.IRRefImage = uiimage(app.GridLayout);
            app.ChoosereferenceIRimageButton = uibutton(app.GridLayout, 'push');
            app.ChoosereferenceIRimageButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosereferenceIRimageButtonPushed, true);
            app.ChoosereferenceIRimageButton.Text = 'Choose reference IR image';
            app.VISRefImage = uiimage(app.GridLayout);
            app.ChoosereferenceVISimageButton = uibutton(app.GridLayout, 'push');
            app.ChoosereferenceVISimageButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosereferenceVISimageButtonPushed, true);
            app.ChoosereferenceVISimageButton.Text = 'Choose reference VIS image';
            app.Image2 = uiimage(app.GridLayout);
            app.ResultLabel = uilabel(app.GridLayout);
            app.ResultLabel.Text = 'Result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = termo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end